classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        JogmmEditField                matlab.ui.control.NumericEditField
        JogmmEditFieldLabel           matlab.ui.control.Label
        YLabel_2                      matlab.ui.control.Label
        PLabel                        matlab.ui.control.Label
        RLabel                        matlab.ui.control.Label
        ZLabel                        matlab.ui.control.Label
        YLabel                        matlab.ui.control.Label
        XLabel                        matlab.ui.control.Label
        RobotEndEffectorLabel         matlab.ui.control.Label
        ZButton                       matlab.ui.control.Button
        ZButton_2                     matlab.ui.control.Button
        YButton                       matlab.ui.control.Button
        YButton_2                     matlab.ui.control.Button
        XButton_2                     matlab.ui.control.Button
        XButton                       matlab.ui.control.Button
        TeachLabel                    matlab.ui.control.Label
        Link7Slider                   matlab.ui.control.Slider
        Link7SliderLabel              matlab.ui.control.Label
        Link6Slider                   matlab.ui.control.Slider
        Link6SliderLabel              matlab.ui.control.Label
        Link5Slider                   matlab.ui.control.Slider
        Link5SliderLabel              matlab.ui.control.Label
        Link4Slider                   matlab.ui.control.Slider
        Link4SliderLabel              matlab.ui.control.Label
        Link3Slider                   matlab.ui.control.Slider
        Link3SliderLabel              matlab.ui.control.Label
        Link2Slider                   matlab.ui.control.Slider
        Link2SliderLabel              matlab.ui.control.Label
        Link1Slider                   matlab.ui.control.Slider
        Link1SliderLabel              matlab.ui.control.Label
        SelectRobotButtonGroup        matlab.ui.container.ButtonGroup
        RunningLamp                   matlab.ui.control.Lamp
        RunningLampLabel              matlab.ui.control.Label
        ESTOPButton                   matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        Image2                        matlab.ui.control.Image
        PandaButton                   matlab.ui.control.RadioButton
        LinearUR5Button               matlab.ui.control.RadioButton
        EnterangleindegreesLabel      matlab.ui.control.Label
        AngleinRadiansEditFieldLabel  matlab.ui.control.Label
        AngleinRadiansEditField       matlab.ui.control.NumericEditField
        AngleinDegreesEditFieldLabel  matlab.ui.control.Label
        AngleinDegreesEditField       matlab.ui.control.NumericEditField
        RobotJoggingLabel             matlab.ui.control.Label
    end

    
    properties (Access = public)
        pandaPos;
        pandaTr;
        pandaRPY;
        ur5Pos;
        ur5Tr;
        ur5RPY;
        self;
        robot = 'Panda';
        qMatrix;
        jogDist = 0.005;
        jog = false;
        estop = false;
        linearUR5;
        panda;
    end
    
    methods (Access = public)
        function UpdateGUI(app, self)
            if app.estop
                if ~app.jog
                    app.self = self;
                    app.pandaPos = app.self.pandaJointAngles;
                    app.pandaTr = app.self.pandaEnd;
                    app.pandaTr = app.pandaTr.T;
                    app.pandaRPY = rad2deg(tr2rpy(app.pandaTr));
                    app.ur5Pos = app.self.ur5JointAngles;
                    app.ur5Tr = app.self.ur5End;
                    app.ur5Tr = app.ur5Tr.T;
                    app.ur5RPY = rad2deg(tr2rpy(app.ur5Tr));
                end
            end
                
            if strcmp(app.robot, 'Panda')
                app.XLabel.Text = ['X = ', num2str(app.pandaTr(1, 4))];
                app.YLabel.Text = ['Y = ', num2str(app.pandaTr(2, 4))];
                app.ZLabel.Text = ['Z = ', num2str(app.pandaTr(3, 4))];
                app.RLabel.Text = ['R = ', num2str(app.pandaRPY(1))];
                app.PLabel.Text = ['P = ', num2str(app.pandaRPY(2))];
                app.YLabel_2.Text = ['Y = ', num2str(app.pandaRPY(3))];

                % app.Link1Slider.Limits = app.self.panda.model.qlim(1,:);
                % app.Link2Slider.Limits = app.self.panda.model.qlim(2,:);
                % app.Link3Slider.Limits = app.self.panda.model.qlim(3,:);
                % app.Link4Slider.Limits = app.self.panda.model.qlim(4,:);
                % app.Link5Slider.Limits = app.self.panda.model.qlim(5,:);
                % app.Link6Slider.Limits = app.self.panda.model.qlim(6,:);
                % app.Link7Slider.Limits = app.self.panda.model.qlim(7,:);
            else
                % app.XLabel.Text = ['X = ', num2str(app.ur5Tr(1, 4))];
                % app.YLabel.Text = ['Y = ', num2str(app.ur5Tr(2, 4))];
                % app.ZLabel.Text = ['Z = ', num2str(app.ur5Tr(3, 4))];
                % app.RLabel.Text = ['R = ', num2str(app.ur5RPY(1))];
                % app.PLabel.Text = ['P = ', num2str(app.ur5RPY(2))];
                % app.YLabel_2.Text = ['Y = ', num2str(app.ur5RPY(3))];
                % 
                % app.Link1Slider.Limits = app.self.linearUR5.model.qlim(1,:);
                % app.Link2Slider.Limits = app.self.linearUR5.model.qlim(2,:);
                % app.Link3Slider.Limits = app.self.linearUR5.model.qlim(3,:);
                % app.Link4Slider.Limits = app.self.linearUR5.model.qlim(4,:);
                % app.Link5Slider.Limits = app.self.linearUR5.model.qlim(5,:);
                % app.Link6Slider.Limits = app.self.linearUR5.model.qlim(6,:);
                % app.Link7Slider.Limits = app.self.linearUR5.model.qlim(7,:);
            end
        end

        function UpdateLinks(app)
            app.qMatrix(1) = app.Link1Slider.Value;
            app.qMatrix(2) = app.Link2Slider.Value;
            app.qMatrix(3) = app.Link3Slider.Value;
            app.qMatrix(4) = app.Link4Slider.Value;
            app.qMatrix(5) = app.Link5Slider.Value;
            app.qMatrix(6) = app.Link6Slider.Value;
            app.qMatrix(7) = app.Link7Slider.Value;
        end

        function UpdateSliders(app, qMatrix)
            app.Link1Slider.Value = qMatrix(1);
            app.Link2Slider.Value = qMatrix(2);
            app.Link3Slider.Value = qMatrix(3);
            app.Link4Slider.Value = qMatrix(4);
            app.Link5Slider.Value = qMatrix(5);
            app.Link6Slider.Value = qMatrix(6);
            app.Link7Slider.Value = qMatrix(7);
        end


        function translation = StepRobot(app)
            app.pandaTr
            translation = app.pandaTr;
        end

        function estop = CheckEstop(app)
            estop = app.estop;
        end
            

        function qMatrix = GetLinkValues(app)
            app.UpdateLinks();
            qMatrix = app.qMatrix;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, linearUR5, panda)
            disp('start up boi')
            % app.self = self;

            app.JogmmEditField.Value = app.jogDist * 1000;

            % app.pandaPos = app.self.pandaJointAngles;
            % app.pandaTr = app.self.pandaEnd;
            % app.pandaTr = app.pandaTr.T;
            % app.pandaRPY = rad2deg(tr2rpy(app.pandaTr));
            % app.ur5Pos = app.self.ur5JointAngles;
            % app.ur5Tr = app.self.ur5End;
            % app.ur5Tr = app.ur5Tr.T;
            % app.ur5RPY = rad2deg(tr2rpy(app.ur5Tr));

            app.linearUR5 = linearUR5;
            app.panda = panda;

            % app.Link1Slider.Limits = app.self.panda.model.qlim(1,:);
            % app.Link2Slider.Limits = app.self.panda.model.qlim(2,:);
            % app.Link3Slider.Limits = app.self.panda.model.qlim(3,:);
            % app.Link4Slider.Limits = app.self.panda.model.qlim(4,:);
            % app.Link5Slider.Limits = app.self.panda.model.qlim(5,:);
            % app.Link6Slider.Limits = app.self.panda.model.qlim(6,:);
            % app.Link7Slider.Limits = app.self.panda.model.qlim(7,:);

            app.Link1Slider.Limits = app.panda.model.qlim(1,:);
            app.Link2Slider.Limits = app.panda.model.qlim(2,:);
            app.Link3Slider.Limits = app.panda.model.qlim(3,:);
            app.Link4Slider.Limits = app.panda.model.qlim(4,:);
            app.Link5Slider.Limits = app.panda.model.qlim(5,:);
            app.Link6Slider.Limits = app.panda.model.qlim(6,:);
            app.Link7Slider.Limits = app.panda.model.qlim(7,:);
            
        end

        % Value changed function: AngleinRadiansEditField
        function AngleinRadiansEditFieldValueChanged(app, event)
            value = app.AngleinRadiansEditField.Value;
            app.AngleinDegreesEditField.Value = value * (180/pi)
        end

        % Value changed function: AngleinDegreesEditField
        function AngleinDegreesEditFieldValueChanged(app, event)
            value = app.AngleinDegreesEditField.Value;
            app.AngleinRadiansEditField.Value = value * (pi/180)
        end

        % Callback function
        function XEditField_11ValueChanged(app, event)
            value = app.XEditField_11.Value;
            app.baseX = value;
        end

        % Callback function
        function YEditField_11ValueChanged(app, event)
            value = app.YEditField_11.Value;
            app.baseY = value;
        end

        % Callback function
        function ZEditField_11ValueChanged(app, event)
            value = app.ZEditField_11.Value;
            app.baseZ = value;
        end

        % Selection changed function: SelectRobotButtonGroup
        function SelectRobotButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectRobotButtonGroup.SelectedObject.Text;
            if strcmp(selectedButton, 'Panda')
                app.robot = app.panda;
            else
                app.robot = app.linearUR5;
            end
            disp(app.robot)
        end

        % Value changed function: Link1Slider
        function Link1SliderValueChanged(app, event)
            app.UpdateLinks();
        end

        % Value changed function: Link2Slider
        function Link2SliderValueChanged(app, event)
            app.UpdateLinks();
        end

        % Value changed function: Link3Slider
        function Link3SliderValueChanged(app, event)
            app.UpdateLinks();
        end

        % Value changed function: Link4Slider
        function Link4SliderValueChanged(app, event)
            app.UpdateLinks();
        end

        % Value changed function: Link5Slider
        function Link5SliderValueChanged(app, event)
            app.UpdateLinks();
        end

        % Value changed function: Link6Slider
        function Link6SliderValueChanged(app, event)
            app.UpdateLinks();
        end

        % Value changed function: Link7Slider
        function Link7SliderValueChanged(app, event)
            app.UpdateLinks();
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            app.pandaTr(1,4)
            app.pandaTr(1,4) = app.pandaTr(1,4) - app.jogDist;
            app.pandaTr(1,4)
            app.jog = true;
        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            app.pandaTr(1,4) = app.pandaTr(1,4) + app.jogDist;
            app.jog = true;
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            app.pandaTr(2,4) = app.pandaTr(2,4) - app.jogDist;
            app.jog = true;
        end

        % Button pushed function: YButton_2
        function YButton_2Pushed(app, event)
            app.pandaTr(2,4) = app.pandaTr(2,4) + app.jogDist;
            app.jog = true;
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            app.pandaTr(3,4) = app.pandaTr(3,4) - app.jogDist;
            app.jog = true;
        end

        % Button pushed function: ZButton_2
        function ZButton_2Pushed(app, event)
            app.pandaTr(3,4) = app.pandaTr(3,4) + app.jogDist;
            app.jog = true;
        end

        % Value changed function: JogmmEditField
        function JogmmEditFieldValueChanged(app, event)
            value = app.JogmmEditField.Value;
            app.jogDist = value/1000;
        end

        % Button pushed function: ESTOPButton
        function ESTOPButtonPushed(app, event)
            app.estop = ~app.estop
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RobotJoggingLabel = uilabel(app.UIFigure);
            app.RobotJoggingLabel.Text = 'Robot Jogging';
            app.AngleinDegreesEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngleinDegreesEditField.ValueChangedFcn = createCallbackFcn(app, @AngleinDegreesEditFieldValueChanged, true);
            app.AngleinDegreesEditFieldLabel = uilabel(app.UIFigure);
            app.AngleinDegreesEditFieldLabel.Text = 'Angle in Degrees';
            app.AngleinRadiansEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngleinRadiansEditField.ValueChangedFcn = createCallbackFcn(app, @AngleinRadiansEditFieldValueChanged, true);
            app.AngleinRadiansEditFieldLabel = uilabel(app.UIFigure);
            app.AngleinRadiansEditFieldLabel.Text = 'Angle in Radians';
            app.EnterangleindegreesLabel = uilabel(app.UIFigure);
            app.EnterangleindegreesLabel.Text = 'Enter angle in degrees.';
            app.SelectRobotButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectRobotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectRobotButtonGroupSelectionChanged, true);
            app.SelectRobotButtonGroup.Title = 'Select Robot';
            app.LinearUR5Button = uiradiobutton(app.SelectRobotButtonGroup);
            app.LinearUR5Button.Text = 'LinearUR5';
            app.PandaButton = uiradiobutton(app.SelectRobotButtonGroup);
            app.PandaButton.Text = 'Panda';
            app.PandaButton.Value = true;
            app.Image2 = uiimage(app.SelectRobotButtonGroup);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '@Panda', 'panda.jpg');
            app.Image = uiimage(app.SelectRobotButtonGroup);
            app.Image.ImageSource = fullfile(pathToMLAPP, '@LinearUR5custom', 'ur5.jpg');
            app.ESTOPButton = uibutton(app.SelectRobotButtonGroup, 'push');
            app.ESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @ESTOPButtonPushed, true);
            app.ESTOPButton.Text = 'E-STOP';
            app.RunningLampLabel = uilabel(app.SelectRobotButtonGroup);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.SelectRobotButtonGroup);
            app.Link1SliderLabel = uilabel(app.UIFigure);
            app.Link1SliderLabel.Text = 'Link 1';
            app.Link1Slider = uislider(app.UIFigure);
            app.Link1Slider.Limits = [-1 1];
            app.Link1Slider.ValueChangedFcn = createCallbackFcn(app, @Link1SliderValueChanged, true);
            app.Link2SliderLabel = uilabel(app.UIFigure);
            app.Link2SliderLabel.Text = 'Link 2';
            app.Link2Slider = uislider(app.UIFigure);
            app.Link2Slider.Limits = [-1 1];
            app.Link2Slider.ValueChangedFcn = createCallbackFcn(app, @Link2SliderValueChanged, true);
            app.Link3SliderLabel = uilabel(app.UIFigure);
            app.Link3SliderLabel.Text = 'Link 3';
            app.Link3Slider = uislider(app.UIFigure);
            app.Link3Slider.Limits = [-1 1];
            app.Link3Slider.ValueChangedFcn = createCallbackFcn(app, @Link3SliderValueChanged, true);
            app.Link4SliderLabel = uilabel(app.UIFigure);
            app.Link4SliderLabel.Text = 'Link 4';
            app.Link4Slider = uislider(app.UIFigure);
            app.Link4Slider.Limits = [-1 1];
            app.Link4Slider.ValueChangedFcn = createCallbackFcn(app, @Link4SliderValueChanged, true);
            app.Link5SliderLabel = uilabel(app.UIFigure);
            app.Link5SliderLabel.Text = 'Link 5';
            app.Link5Slider = uislider(app.UIFigure);
            app.Link5Slider.Limits = [-1 1];
            app.Link5Slider.ValueChangedFcn = createCallbackFcn(app, @Link5SliderValueChanged, true);
            app.Link6SliderLabel = uilabel(app.UIFigure);
            app.Link6SliderLabel.Text = 'Link 6';
            app.Link6Slider = uislider(app.UIFigure);
            app.Link6Slider.Limits = [-1 1];
            app.Link6Slider.ValueChangedFcn = createCallbackFcn(app, @Link6SliderValueChanged, true);
            app.Link7SliderLabel = uilabel(app.UIFigure);
            app.Link7SliderLabel.Text = 'Link 7';
            app.Link7Slider = uislider(app.UIFigure);
            app.Link7Slider.Limits = [-1 1];
            app.Link7Slider.ValueChangedFcn = createCallbackFcn(app, @Link7SliderValueChanged, true);
            app.TeachLabel = uilabel(app.UIFigure);
            app.TeachLabel.Text = 'Teach';
            app.XButton = uibutton(app.UIFigure, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = '- X';
            app.XButton_2 = uibutton(app.UIFigure, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = '+ X';
            app.YButton_2 = uibutton(app.UIFigure, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YButton_2Pushed, true);
            app.YButton_2.Text = '+ Y';
            app.YButton = uibutton(app.UIFigure, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = '- Y';
            app.ZButton_2 = uibutton(app.UIFigure, 'push');
            app.ZButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZButton_2Pushed, true);
            app.ZButton_2.Text = '+ Z';
            app.ZButton = uibutton(app.UIFigure, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = '- Z';
            app.RobotEndEffectorLabel = uilabel(app.UIFigure);
            app.RobotEndEffectorLabel.Text = 'Robot End Effector';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X = ';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y = ';
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z = ';
            app.RLabel = uilabel(app.UIFigure);
            app.RLabel.Text = 'R = ';
            app.PLabel = uilabel(app.UIFigure);
            app.PLabel.Text = 'P = ';
            app.YLabel_2 = uilabel(app.UIFigure);
            app.YLabel_2.Text = 'Y = ';
            app.JogmmEditFieldLabel = uilabel(app.UIFigure);
            app.JogmmEditFieldLabel.Text = 'Jog (mm)';
            app.JogmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.JogmmEditField.ValueChangedFcn = createCallbackFcn(app, @JogmmEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end